classdef interactive_rotate_polygon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        rRotateCCWtRotateCWLabel        matlab.ui.control.Label
        ResettoinitialalignmentButton   matlab.ui.control.Button
        SavetransformtoworkspaceButton  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        polygon_orig % Original copy of polygon
        polygon_temp 
        rotation = 0;
    end

    properties (Access = public)
        rotation_tform=0; %This is the final alignment tform that is passed back
    end

    methods (Access = private)

        function updatePlot(app)
            % Display new polygon orientation
            plot(app.polygon_temp, 'Parent', app.UIAxes);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ROI_polygon)
            app.polygon_orig = ROI_polygon;
            app.polygon_temp = app.polygon_orig;
            app.rotation_tform = [];
            updatePlot(app);
        end

        % Button pushed function: SavetransformtoworkspaceButton
        function SaveTForm_ButtonPushed(app, event)
            app.rotation_tform = app.rotation;
        end

        % Button pushed function: ResettoinitialalignmentButton
        function Reset_ButtonPushed(app, event)
            app.rotation = 0;
            app.polygon_temp = app.polygon_orig;
            updatePlot(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'r' %CCW rotation
                    app.rotation = app.rotation-0.1;
                case 't' %CW rotation
                    app.rotation = app.rotation+0.1;
            end
            app.polygon_temp = rotate(app.polygon_orig, app.rotation); %Apply transform to warp image2  into new alignment
            updatePlot(app); %Update plot with new alignment

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Rotate to align ROI with vertical and horizontal axes')
            app.UIAxes.YDir = 'reverse';
            app.SavetransformtoworkspaceButton = uibutton(app.GridLayout, 'push');
            app.SavetransformtoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTForm_ButtonPushed, true);
            app.SavetransformtoworkspaceButton.Text = {'Save transform'; ' to workspace'};
            app.ResettoinitialalignmentButton = uibutton(app.GridLayout, 'push');
            app.ResettoinitialalignmentButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_ButtonPushed, true);
            app.ResettoinitialalignmentButton.Text = {'Reset to'; ' initial alignment'};
            app.rRotateCCWtRotateCWLabel = uilabel(app.GridLayout);
            app.rRotateCCWtRotateCWLabel.Text = {'r = Rotate CCW'; 't = Rotate CW'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interactive_rotate_polygon(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end