classdef preview_iDop_video < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SavemovietofileButton       matlab.ui.control.Button
        StartStopMovieButton        matlab.ui.control.Button
        FramespersecondDropDown     matlab.ui.control.DropDown
        FramespersecondLabel        matlab.ui.control.Label
        PlayThroughEntireSequenceButton  matlab.ui.control.Button
        FramepercentileSlider       matlab.ui.control.Slider
        FramepercentileSliderLabel  matlab.ui.control.Label
        SessionParametersLabel      matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        iDop_flat % Flattened iDop image, so that it is 1D for time.
        video_speed % Frames per time interval. 
        time_between_frames % Time interval between next plot update
        stop_movie % Boolean for start/stop movie
        current_frame % Keep track of current movie frame
        session_run_list % List of the data loaded
        SessionParameters
    end
    
    methods (Access = private)
        % This function is used to relabel the plot title to see where in
        % the sequence the current frame is from.
        function relabel_plot_title(app, value)
            title(app.UIAxes,sprintf("%0.2f%% through iDop sequence", value))
        end
        
        % This function is used to start movie playback from wherever it
        % might currently be.
        function start_movie_playback(app)
            if app.current_frame <= 0
                app.current_frame = 1;
            end
            
            % While current_frame is less than length of sequence, play
            % through the movie.
            while app.current_frame < size(app.iDop_flat,3)
                if app.stop_movie
                    break;
                end
                imagesc(app.UIAxes,app.iDop_flat(:, :, app.current_frame));
                relabel_plot_title(app,  100 * app.current_frame/size(app.iDop_flat,3));
                app.FramepercentileSlider.Value =  100 * app.current_frame/size(app.iDop_flat,3);
                app.current_frame = app.current_frame + app.video_speed;
                pause(app.time_between_frames);
            end
            
            % Brings frame number back within proper range at very end if
            % it exceeds the range.
            app.current_frame = app.current_frame - app.video_speed;
        end
        
        % This function is save movie playback to a movie file
        function playback_and_save_to_file(app)
            if app.current_frame <= 0
                app.current_frame = 1;
            end
            
            % Open the file to save the video to.
            filepath = uigetdir(getUserDataPath('pathType', 'output'));
            if size(app.session_run_list, 1) > 1
                if all(app.session_run_list(:, 1))
                    session_run_string = sprintf('S%dRmultiple', app.session_run_list(1));
                else
                    session_run_string = 'SmultipleRmultiple';
                end
            else
                session_run_string = sprintf('S%dR%d', app.session_run_list(1), app.session_run_list(2));
                if app.SessionParameters.motionCorrection
                    session_run_string = [session_run_string sprintf('+%s', string(app.SessionParameters.method))];
                end
            end
            v = VideoWriter(fullfile(filepath, sprintf('iDop_movie_%s_dg%s', session_run_string, datestr(today(), 'yyyymmdd'))), 'MPEG-4');
            
            % Set the framerate
            switch app.FramespersecondDropDown.Value
                case 'Entire video in 10 seconds'
                    v.FrameRate = 60;
                    frame_increment = round(size(app.iDop_flat,3)/(v.FrameRate*10));
                otherwise
                    v.FrameRate = round(1/app.time_between_frames);
                    frame_increment = 1;
            end
            
            open(v);
            
            % While current_frame is less than length of sequence, play
            % through the movie.
            while app.current_frame < size(app.iDop_flat,3)
                if app.stop_movie
                    close(v);
                    break;
                end
                imagesc(app.UIAxes,app.iDop_flat(:, :, app.current_frame));
                relabel_plot_title(app,  100 * app.current_frame/size(app.iDop_flat,3));
                app.FramepercentileSlider.Value =  100 * app.current_frame/size(app.iDop_flat,3);
                app.current_frame = app.current_frame + frame_increment;
                
                % Write frame to video
                frame = getframe(app.UIFigure);
                writeVideo(v, frame);
            end
            
            if exist('v', 'var')
                % If video still exists, then close it at end.
                close(v);
            end
            
            % Brings frame number back within proper range at very end if
            % it exceeds the range.
            app.current_frame = app.current_frame - app.video_speed;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, iDop, SessionRunList, SessionParameters)
            % iDop = iDop structure (4D array)
            % SessionRunList = [session run]; 1x2 matrix
            % SessionParameters = coreParam structure assumed
            if ndims(iDop) == 4
                app.iDop_flat = reshape(iDop,size(iDop,1), size(iDop,2), []);
            else
                app.iDop_flat = iDop;
            end
            app.iDop_flat = nthroot(app.iDop_flat, 3);
            app.video_speed = round(size(app.iDop_flat,3)/100);
            app.time_between_frames = 0.1;
            app.stop_movie = true;
            app.current_frame = 1;
            if exist('SessionParameters', 'var')
                app.SessionParameters = SessionParameters;
            else
                % Initialize the variable
                SessionParameters.motionCorrection = false;
                SessionParameters.method = 'null';
                SessionParameters.genDate = 'null';
                app.SessionParameters = SessionParameters;
            end
            imagesc(app.UIAxes, app.iDop_flat(:,:,1))
            axis(app.UIAxes, 'image');
            % Add the session parameters that you want the user to see
            app.SessionParametersLabel.Text = app.SessionParametersLabel.Text + ...
                sprintf("\nSession: %d", SessionRunList(1)) + ...
                sprintf("\nRun: %d", SessionRunList(2)) + ...
                sprintf("\nMotion corrected: %s", string(SessionParameters.motionCorrection)) + ...
                sprintf("\nDate generated: %s", SessionParameters.genDate);
            
            %% Initialize the limits of slider to be valid
            app.FramepercentileSlider.Limits = [1/size(app.iDop_flat,3) 100];
            app.FramepercentileSlider.MajorTicks(1) = app.FramepercentileSlider.Limits(1);
            app.FramepercentileSlider.MajorTickLabels{1} = sprintf('%0.2f',app.FramepercentileSlider.Limits(1));
            
            % Put some variables into shared variable
            app.session_run_list = SessionRunList;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {291, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: FramepercentileSlider, 
        % FramepercentileSlider
        function FramepercentileSliderValueChanged(app, event)
            app.current_frame = round(event.Value/100 * size(app.iDop_flat,3));
            
            if app.current_frame < 1
                app.current_frame = 1;
            end
            imagesc(app.UIAxes,app.iDop_flat(:,:,app.current_frame));
            axis(app.UIAxes, 'image');
            relabel_plot_title(app, event.Value);
        end

        % Button pushed function: PlayThroughEntireSequenceButton
        function PlayThroughEntireSequenceButtonPushed(app, event)
            app.stop_movie = false;
            app.current_frame = 1;
            start_movie_playback(app);
        end

        % Value changed function: FramespersecondDropDown
        function FramespersecondDropDownValueChanged(app, event)
            value = app.FramespersecondDropDown.Value;
            switch value
                case 'Entire video in 10 seconds'
                    app.video_speed = round(size(app.iDop_flat,3)/100);
                    app.time_between_frames = 0.1;
                case '1 frame/sec'
                    app.video_speed = 1;
                    app.time_between_frames = 1;
                case '2 frames/sec'
                    app.video_speed = 1;
                    app.time_between_frames = 0.5;
                case '10 frames/sec'
                    app.video_speed = 1;
                    app.time_between_frames = 0.1;
                case '20 frames/sec'
                    app.video_speed = 1;
                    app.time_between_frames = 1/20;
                case '40 frames/sec'
                    app.video_speed = 1;
                    app.time_between_frames = 1/40;
       
            end
        end

        % Button pushed function: StartStopMovieButton
        function StartStopMovieButtonPushed(app, event)
            app.stop_movie = ~app.stop_movie;
            if ~app.stop_movie
                start_movie_playback(app);
            end
        end

        % Button pushed function: SavemovietofileButton
        function SavemovietofileButtonPushed(app, event)
            app.stop_movie = false;
            app.current_frame = 1;
            playback_and_save_to_file(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SessionParametersLabel = uilabel(app.LeftPanel);
            app.SessionParametersLabel.Text = 'Session Parameters:';
            app.FramepercentileSliderLabel = uilabel(app.LeftPanel);
            app.FramepercentileSliderLabel.Text = 'Frame (percentile)';
            app.FramepercentileSlider = uislider(app.LeftPanel);
            app.FramepercentileSlider.Limits = [0.001 100];
            app.FramepercentileSlider.MajorTicks = [0.001 20 40 60 80 100];
            app.FramepercentileSlider.ValueChangedFcn = createCallbackFcn(app, @FramepercentileSliderValueChanged, true);
            app.FramepercentileSlider.ValueChangingFcn = createCallbackFcn(app, @FramepercentileSliderValueChanged, true);
            app.FramepercentileSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.FramepercentileSlider.Value = 1;
            app.PlayThroughEntireSequenceButton = uibutton(app.LeftPanel, 'push');
            app.PlayThroughEntireSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @PlayThroughEntireSequenceButtonPushed, true);
            app.PlayThroughEntireSequenceButton.Text = {'Play Through'; 'Entire Sequence'};
            app.FramespersecondLabel = uilabel(app.LeftPanel);
            app.FramespersecondLabel.Text = {'Frames per'; 'second'};
            app.FramespersecondDropDown = uidropdown(app.LeftPanel);
            app.FramespersecondDropDown.Items = {'Entire video in 10 seconds', '1 frame/sec', '2 frames/sec', '10 frames/sec', '20 frames/sec', '40 frames/sec'};
            app.FramespersecondDropDown.ValueChangedFcn = createCallbackFcn(app, @FramespersecondDropDownValueChanged, true);
            app.FramespersecondDropDown.Value = 'Entire video in 10 seconds';
            app.StartStopMovieButton = uibutton(app.LeftPanel, 'push');
            app.StartStopMovieButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopMovieButtonPushed, true);
            app.StartStopMovieButton.Text = 'Start/Stop Movie';
            app.SavemovietofileButton = uibutton(app.LeftPanel, 'push');
            app.SavemovietofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavemovietofileButtonPushed, true);
            app.SavemovietofileButton.Text = 'Save movie to file';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'iDop sequence')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preview_iDop_video(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end